classdef start_program < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DisplayResultsButton_2        matlab.ui.control.Button
        RunFEAAnalysisButton          matlab.ui.control.Button
        UseDefaultToolButton          matlab.ui.control.Button
        AwaitinguserinteractionLabel  matlab.ui.control.Label
        EnterCustomToolParametersButton  matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function start(app, event)

        end

        % Button pushed function: EnterCustomToolParametersButton
        function start_py_gui(app, event)
            app.AwaitinguserinteractionLabel.Text = 'Setting up custom tool';
            % Run python gui to create custom mesh
            project_folder = fileparts(mfilename('fullpath'));
            app.AwaitinguserinteractionLabel.Text = 'Custom parameter selection in another window';
            system(fullfile(project_folder, 'dist/mesh_generation.exe'));
            app.AwaitinguserinteractionLabel.Text = 'Ready to perform analysis.';
        end

        % Button pushed function: UseDefaultToolButton
        function default_tool(app, event)
            app.AwaitinguserinteractionLabel.Text = 'Setting default values and performing analysis...';
            % Display tool mesh
            image(imread('default_cutting_tool_preview.png'))
            % Run program for default tool
            run('run_cutting_tool_case')
            app.AwaitinguserinteractionLabel.Text = 'Analysis complete, ready to display results.';
        end

        % Button pushed function: RunFEAAnalysisButton
        function run_main_analysis(app, event)
            app.AwaitinguserinteractionLabel.Text = 'Running FEA Analysis...';
            % Run code developed in class
            run('Main.m')
            app.AwaitinguserinteractionLabel.Text = 'Analysis complete, ready to display results.';
        end

        % Button pushed function: DisplayResultsButton_2
        function display_results(app, event)
            app.AwaitinguserinteractionLabel.Text = 'Displaying results in another window...';
            project_folder = fileparts(mfilename('fullpath'));
            system(fullfile(project_folder, 'dist/visualisation.exe'));
            app.AwaitinguserinteractionLabel.Text = 'Awaiting user interaction...';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterCustomToolParametersButton = uibutton(app.UIFigure, 'push');
            app.EnterCustomToolParametersButton.ButtonPushedFcn = createCallbackFcn(app, @start_py_gui, true);
            app.EnterCustomToolParametersButton.Tag = 'Start-button';
            app.EnterCustomToolParametersButton.Text = {'Enter Custom Tool '; 'Parameters'};
            app.AwaitinguserinteractionLabel = uilabel(app.UIFigure);
            app.AwaitinguserinteractionLabel.Tag = 'Status';
            app.AwaitinguserinteractionLabel.Text = 'Awaiting user interaction...';
            app.UseDefaultToolButton = uibutton(app.UIFigure, 'push');
            app.UseDefaultToolButton.ButtonPushedFcn = createCallbackFcn(app, @default_tool, true);
            app.UseDefaultToolButton.Tag = 'Start-button';
            app.UseDefaultToolButton.Text = 'Use Default Tool';
            app.RunFEAAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunFEAAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @run_main_analysis, true);
            app.RunFEAAnalysisButton.Tag = 'Start-button';
            app.RunFEAAnalysisButton.Text = 'Run FEA Analysis';
            app.DisplayResultsButton_2 = uibutton(app.UIFigure, 'push');
            app.DisplayResultsButton_2.ButtonPushedFcn = createCallbackFcn(app, @display_results, true);
            app.DisplayResultsButton_2.Tag = 'Start-button';
            app.DisplayResultsButton_2.Text = 'Display Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = start_program

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end